classdef mg_aha_segm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        InvertDirectionButton  matlab.ui.control.Button
        Button_p25             matlab.ui.control.Button
        Button_p5              matlab.ui.control.Button
        Button_p1              matlab.ui.control.Button
        Button_m1              matlab.ui.control.Button
        Button_m5              matlab.ui.control.Button
        Button_m25             matlab.ui.control.Button
        saveexitButton         matlab.ui.control.Button
    end

%%  
properties (Access = public)
end

properties (Access = private)
    I
    roi
    settings
end

%%
methods (Access = private)

function results = calc_draw_segms(app)

%% segmentation

app.roi.aha_n_segms = 10;
app.roi.aha_slice   = app.settings.slice_name;
    
switch app.settings.slice_name
    case 'basal'
    app.roi.aha_segm_names = { 'basal anterior';
                               'basal anteroseptal';
                               'basal inferoseptal';
                               'basal inferior';
                               'basal inferolateral';
                               'basal anterolateral';
                               'basal anterior';
                               'basal septal';
                               'basal posterior';
                               'basal lateral'};       
    case 'medial'
    app.roi.aha_segm_names = { 'mid anterior';
                               'mid anteroseptal';
                               'mid inferoseptal';
                               'mid inferior';
                               'mid inferolateral';
                               'mid anterolateral';
                               'mid anterior';
                               'mid septal';
                               'mid posterior';
                               'mid lateral'};  
    case 'apical'
    app.roi.aha_segm_names = { 'apical anterior';
                               'apical anteroseptal';
                               'apical inferoseptal';
                               'apical inferior';
                               'apical inferolateral';
                               'apical anterolateral';
                               'apical anterior';
                               'apical septal';
                               'apical posterior';
                               'apical lateral'};  
end


[sy, sx]            = size(app.I);
app.roi.aha_segms_s = zeros(12,sy,sx); % aha6, aha4, sum(aha6), sum(ah4)
app.roi.aha_segms_w = zeros(12,sy,sx); % aha6, aha4, sum(aha6), sum(ah4)

% 6 segments
for j=1:6
    aha_angle                   = 60*pi/180;
    center_angle                = app.roi.ref_angle - app.roi.segm_direction * aha_angle/2 + (j-1) * app.roi.segm_direction * aha_angle;
    segm_temp                   = mg_get_aha_segm_mask(app.I, app.roi.yc, app.roi.xc, center_angle, aha_angle/2);
    segm_temp_s                 = segm_temp .* app.roi.mask_smooth;
    segm_temp_w                 = segm_temp .* app.roi.mask_weighted;
    app.roi.aha_c_angles(j,1)   = center_angle;
    app.roi.aha_angles(j,1)     = wrapTo2Pi(center_angle - app.roi.segm_direction * aha_angle/2);
    app.roi.aha_segms_s(j,:,:)  = segm_temp_s(:,:);
    app.roi.aha_segms_w(j,:,:)  = segm_temp_w(:,:);
    app.roi.aha_segms_s(11,:,:) = squeeze(app.roi.aha_segms_s(11,:,:)) + segm_temp_s(:,:)*j/6;
    app.roi.aha_segms_w(11,:,:) = squeeze(app.roi.aha_segms_w(11,:,:)) + segm_temp_w(:,:)*j/6;       
end
clear aha_angle center_angle segm_temp segm_temp_s segm_temp_w;

% 4 segments
for j=1:4
    aha_angle                    = 90*pi/180;
    center_angle                 = app.roi.ref_angle + (j-1) * app.roi.segm_direction * aha_angle - app.roi.segm_direction * 30*pi/180;
    segm_temp                    = mg_get_aha_segm_mask(app.I, app.roi.yc, app.roi.xc, center_angle, aha_angle/2);
    segm_temp_s                  = segm_temp .* app.roi.mask_smooth;
    segm_temp_w                  = segm_temp .* app.roi.mask_weighted;
    app.roi.aha_c_angles(j+6,1)  = center_angle;
    app.roi.aha_angles(j+6,1)    = wrapTo2Pi(center_angle - app.roi.segm_direction * aha_angle/2);
    app.roi.aha_segms_s(j+6,:,:) = segm_temp_s(:,:);
    app.roi.aha_segms_w(j+6,:,:) = segm_temp_w(:,:);
    app.roi.aha_segms_s(12,:,:)  = squeeze(app.roi.aha_segms_s(12,:,:)) + segm_temp_s(:,:)*j/4;
    app.roi.aha_segms_w(12,:,:)  = squeeze(app.roi.aha_segms_w(12,:,:)) + segm_temp_w(:,:)*j/4;       
end
clear aha_angle center_angle segm_temp segm_temp_s segm_temp_w;    

% show segmentation
figure(7375)
subplot(1,2,1)
hold on
imagesc(app.I, [app.settings.lb, app.settings.ub])
axis image; axis off;
colormap(app.settings.colormap)
plot(app.roi.epi.xpos_smooth,  app.roi.epi.ypos_smooth,  'r', 'LineWidth', 2)
plot(app.roi.endo.xpos_smooth, app.roi.endo.ypos_smooth, 'g', 'LineWidth', 2)
temp_r = mean(sqrt((app.roi.epi.xpos_smooth-app.roi.xc).^2+(app.roi.epi.ypos_smooth-app.roi.yc).^2));
for k=1:6
    plot( app.roi.xc+[0 size(app.I,1)*cos(app.roi.aha_angles(k))], app.roi.yc+[0 size(app.I,1)*sin(app.roi.aha_angles(k))], 'k-' )
    text( app.roi.xc+1.3*temp_r*cos(app.roi.aha_c_angles(k)), app.roi.yc+1.3*temp_r*sin(app.roi.aha_c_angles(k)), num2str(k), 'Color', 'red', 'FontSize', 14 )
end
xlim(app.settings.xlims)
ylim(app.settings.ylims)
title('6 AHA Segms')
hold off

subplot(1,2,2)
hold on
imagesc(app.I, [app.settings.lb, app.settings.ub])
axis image; axis off;
colormap(app.settings.colormap)
plot(app.roi.epi.xpos_smooth,  app.roi.epi.ypos_smooth,  'r', 'LineWidth', 2)
plot(app.roi.endo.xpos_smooth, app.roi.endo.ypos_smooth, 'g', 'LineWidth', 2)
temp_r = mean(sqrt((app.roi.epi.xpos_smooth-app.roi.xc).^2+(app.roi.epi.ypos_smooth-app.roi.yc).^2));
for k=1:4
    plot( app.roi.xc+[0 size(app.I,1)*cos(app.roi.aha_angles(k+6))], app.roi.yc+[0 size(app.I,1)*sin(app.roi.aha_angles(k+6))], 'k-' )
    text( app.roi.xc+1.3*temp_r*cos(app.roi.aha_c_angles(k+6)), app.roi.yc+1.3*temp_r*sin(app.roi.aha_c_angles(k+6)), num2str(k), 'Color', 'red', 'FontSize', 14 )
end
xlim(app.settings.xlims)
ylim(app.settings.ylims)
title('4 AHA Segms')
hold off

%%

end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, I, roi, settings)
            app.I        = I;
            app.roi      = roi;
            app.settings = settings;
            if ~isfield(roi, 'ref_angle')
                app.roi.ref_angle = 0;
            end
            if ~isfield(roi, 'segm_direction')
                app.roi.segm_direction = 1;
            end            
            calc_draw_segms(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin('base', 'roi', app.roi);
            delete(app);
            close(7375)
        end

        % Button pushed function: saveexitButton
        function saveexitButtonPushed(app, event)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: Button_m25
        function Button_m25Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle - 25*pi/180);
            calc_draw_segms(app);
        end

        % Button pushed function: Button_m5
        function Button_m5Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle - 5*pi/180);
            calc_draw_segms(app);
        end

        % Button pushed function: Button_m1
        function Button_m1Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle - 1*pi/180);
            calc_draw_segms(app);
        end

        % Button pushed function: Button_p1
        function Button_p1Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle + 1*pi/180);
            calc_draw_segms(app);
        end

        % Button pushed function: Button_p5
        function Button_p5Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle + 5*pi/180);
            calc_draw_segms(app);
        end

        % Button pushed function: Button_p25
        function Button_p25Pushed(app, event)
            app.roi.ref_angle = wrapTo2Pi(app.roi.ref_angle + 25*pi/180);
            calc_draw_segms(app);
        end

        % Callback function
        function InvertDirectionCheckBoxValueChanged(app, event)

        end

        % Button pushed function: InvertDirectionButton
        function InvertDirectionButtonPushed(app, event)
            app.roi.segm_direction = -app.roi.segm_direction;
            calc_draw_segms(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.saveexitButton = uibutton(app.UIFigure, 'push');
            app.saveexitButton.ButtonPushedFcn = createCallbackFcn(app, @saveexitButtonPushed, true);
            app.saveexitButton.Text = 'save & exit';
            app.Button_m25 = uibutton(app.UIFigure, 'push');
            app.Button_m25.ButtonPushedFcn = createCallbackFcn(app, @Button_m25Pushed, true);
            app.Button_m25.Text = '-25';
            app.Button_m5 = uibutton(app.UIFigure, 'push');
            app.Button_m5.ButtonPushedFcn = createCallbackFcn(app, @Button_m5Pushed, true);
            app.Button_m5.Text = '-5';
            app.Button_m1 = uibutton(app.UIFigure, 'push');
            app.Button_m1.ButtonPushedFcn = createCallbackFcn(app, @Button_m1Pushed, true);
            app.Button_m1.Text = '-1';
            app.Button_p1 = uibutton(app.UIFigure, 'push');
            app.Button_p1.ButtonPushedFcn = createCallbackFcn(app, @Button_p1Pushed, true);
            app.Button_p1.Text = '+1';
            app.Button_p5 = uibutton(app.UIFigure, 'push');
            app.Button_p5.ButtonPushedFcn = createCallbackFcn(app, @Button_p5Pushed, true);
            app.Button_p5.Text = '+5';
            app.Button_p25 = uibutton(app.UIFigure, 'push');
            app.Button_p25.ButtonPushedFcn = createCallbackFcn(app, @Button_p25Pushed, true);
            app.Button_p25.Text = '+25';
            app.InvertDirectionButton = uibutton(app.UIFigure, 'push');
            app.InvertDirectionButton.ButtonPushedFcn = createCallbackFcn(app, @InvertDirectionButtonPushed, true);
            app.InvertDirectionButton.Text = 'Invert Direction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mg_aha_segm_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end